classdef CPR_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlaySongButton                  matlab.ui.control.StateButton
        ListentothissongTheBPMmatchestherateatwhichonLabel  matlab.ui.control.Label
        OverallScoreEditField           matlab.ui.control.EditField
        OverallScoreEditFieldLabel      matlab.ui.control.Label
        ConsistencyScoreEditField       matlab.ui.control.EditField
        ConsistencyScoreEditFieldLabel  matlab.ui.control.Label
        SpeedScoreEditField             matlab.ui.control.EditField
        SpeedScoreEditFieldLabel        matlab.ui.control.Label
        BeginbyuploadingCPRdatabelowLabel  matlab.ui.control.Label
        CPRCheckerLabel                 matlab.ui.control.Label
        UploadDataButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [max_amp, max_freq] = make_freq_plot_max(app, x, Fs, plotty)
            % make a frequency domain plot with frequency labels
            % and output the max amplitude and the corresponding dominant frequency(Hz)
            %
            %   x = input signal
            %   Fs = sampling frequency in Hz
            
            if(mod(length(x),2) ~=0)
                x = x(1:end-1);
            end
            % frequency domain
            freq_domain = linspace(-Fs/2, Fs/2-Fs/length(x),length(x));
            
            % plot signal over frequency domain
            if plotty 
                plot(app.UIAxes, freq_domain, 1/length(x)*fftshift(abs(fft(x))));
                xlim(app.UIAxes,[-10 10]);
                hold on;
                xline(app.UIAxes, 1.67, '--g','Acceptable Frequency Range');     % range of desired frequency shown
                xline(app.UIAxes, 2, '--g');
                xline(app.UIAxes, 0, '--r','Your Frequency');


            end
            

            % find top 2 maximum amplitude value and corresponding index
            [max_amp, max_i] = maxk(1/length(x)*fftshift(abs(fft(x))),3);
            
            % find maximum frequency
            max_freq = freq_domain(max_i);
            
            % take only the positive max frequency
            max_freq = max_freq(max_freq > 0); 
            
%             title("data in frequency domain")
%             ylabel('Magnitude of signal');
%             xlabel('Frequency (Hz)');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% when app is opened, hide these buttons
            
            app.PlaySongButton.Visible = 'off';
            app.ListentothissongTheBPMmatchestherateatwhichonLabel.Visible = 'off';
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.xls');      % obtain file/path to read file
            filename = [path file];
            [numbers, strings, raw] = xlsread(filename);
            accel = numbers(:,5);
            time = numbers(:,1);
            
            N = length(time);
            Fs = round(N/time(end));
            
            [amp, freq] = make_freq_plot_max(app,accel,Fs,true);
            freq_bpm = freq * 60;
            
            seg_time = 5;
            i_start = 1;
            seg_number = 1;
            [~, i_length] = min(abs(time - seg_time));
            i_end = i_start + i_length;
            overlap_i = 100;
            
            while i_end <= length(time)
                time_list(:,seg_number) = time(i_start:i_end);
                data_list(:,seg_number) = accel(i_start:i_end);
                i_start = i_end - overlap_i;
                i_end = i_start + i_length;
                seg_number = seg_number + 1;
            end
            
            amp_list = [];
            freq_list = [];
            for col = 1:size(time_list,2)
                data_part = data_list(:,col);
                [amp_part,freq_part] = make_freq_plot_max(app, data_part,Fs, false);
            %     disp("amp: " + amp)
            %     disp("freq: " + freq)
                % freq prob shouldn't use max
                amp_list(end+1) = max(amp_part);
                freq_list(end+1:end + length(freq_part)) = freq_part;
                amp_consistency = std(amp_list);
                freq_consistency = std(freq_list); 
            end
            
            if freq_consistency < 0.5
                app.ConsistencyScoreEditField.Value = sprintf("good consistency:");
                consistency_coeff = 1;
            elseif freq_consistency <2
                l1 = 'improve your consistency ';
                l2 = 'to do better!';
%                 app.ConsistencyScoreEditField.Value{1} = sprintf('improve your consistency \n');
%                 app.ConsistencyScoreEditField.Value{2} = sprintf('to do better!');
                result = sprintf([l1 '\n' l2]);
                app.ConsistencyScoreEditField.Value = result;
                consistency_coeff = 0.8;
                
                app.PlaySongButton.Visible = 'on';
                app.ListentothissongTheBPMmatchestherateatwhichonLabel.Visible = 'on';
            else
                app.ConsistencyScoreEditField.Value = sprintf("bad consistency: ");
                consistency_coeff = 0.6;
                isoutlier(freq_list)
                
                app.PlaySongButton.Visible = 'on';
                app.ListentothissongTheBPMmatchestherateatwhichonLabel.Visible = 'on';
            end
            
            % calculate score and display
            if freq_bpm <= 120 && freq_bpm >= 100
                score = 100;
               app.SpeedScoreEditField.Value = sprintf("Good job!");
            elseif freq_bpm > 120
                score = 100 - (freq_bpm - 120) / 120 * 100;
                app.SpeedScoreEditField.Value = sprintf("That was too fast!");
            elseif freq_bpm < 100
                score = 100 - (100 - freq_bpm) / 100 * 100;
                app.SpeedScoreEditField.Value = "That was too slow!";
            end
            
            score = round(score,1);
            score = consistency_coeff * score;
            app.OverallScoreEditField.Value = sprintf("Your score is: " + score);
            if score < 30
                app.OverallScoreEditField.Value = sprintf("Try again!");
                app.PlaySongButton.Visible = 'on';
                app.ListentothissongTheBPMmatchestherateatwhichonLabel.Visible = 'on';
            end
        end

        % Value changed function: SpeedScoreEditField
        function SpeedScoreEditFieldValueChanged(app, event)
            %% prints the speed score
            
            value = app.SpeedScoreEditField.Value;
        end

        % Value changed function: ConsistencyScoreEditField
        function ConsistencyScoreEditFieldValueChanged(app, event)
            %% prints the consistency score
            
            value = app.ConsistencyScoreEditField.Value;
        end

        % Value changed function: OverallScoreEditField
        function OverallScoreEditFieldValueChanged(app, event)
            %% prints overall score
            
            value = app.OverallScoreEditField.Value;
        end

        % Value changed function: PlaySongButton
        function PlaySongButtonValueChanged(app, event)
            %% plays and stops the song "Stayin Alive"
            
            value = app.PlaySongButton.Value;
            if (value == 1)                                             % if play button pressed
                fileToRead1 = "StayinAlive.wav";
                s = importdata(fileToRead1);
                sound(s.data,s.fs);
                app.PlaySongButton.Text = "Stop Song";
                app.PlaySongButton.BackgroundColor = [1 0 0];
            else                                                        % if stop button pressed
                clear sound
                app.PlaySongButton.Text = "Play Song";
                app.PlaySongButton.BackgroundColor = [0 0.4470 0.7410];
            end 
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data in frequency domain')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude of Signal')
            zlabel(app.UIAxes, 'Z')
            app.UploadDataButton = uibutton(app.UIFigure, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.CPRCheckerLabel = uilabel(app.UIFigure);
            app.CPRCheckerLabel.Text = 'CPR Checker';
            app.BeginbyuploadingCPRdatabelowLabel = uilabel(app.UIFigure);
            app.BeginbyuploadingCPRdatabelowLabel.Text = {'Begin by uploading'; 'CPR data below:'};
            app.SpeedScoreEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedScoreEditFieldLabel.Text = 'Speed Score:';
            app.SpeedScoreEditField = uieditfield(app.UIFigure, 'text');
            app.SpeedScoreEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedScoreEditFieldValueChanged, true);
            app.ConsistencyScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ConsistencyScoreEditFieldLabel.Text = 'Consistency Score:';
            app.ConsistencyScoreEditField = uieditfield(app.UIFigure, 'text');
            app.ConsistencyScoreEditField.ValueChangedFcn = createCallbackFcn(app, @ConsistencyScoreEditFieldValueChanged, true);
            app.OverallScoreEditFieldLabel = uilabel(app.UIFigure);
            app.OverallScoreEditFieldLabel.Text = 'Overall Score:';
            app.OverallScoreEditField = uieditfield(app.UIFigure, 'text');
            app.OverallScoreEditField.ValueChangedFcn = createCallbackFcn(app, @OverallScoreEditFieldValueChanged, true);
            app.ListentothissongTheBPMmatchestherateatwhichonLabel = uilabel(app.UIFigure);
            app.ListentothissongTheBPMmatchestherateatwhichonLabel.Text = {'Listen to this song; the BPM matches the rate '; 'at which one would want to perform CPR at:'};
            app.PlaySongButton = uibutton(app.UIFigure, 'state');
            app.PlaySongButton.ValueChangedFcn = createCallbackFcn(app, @PlaySongButtonValueChanged, true);
            app.PlaySongButton.Text = 'Play Song';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPR_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end